classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ShowHistogramButton  matlab.ui.control.Button
        TabGroup             matlab.ui.container.TabGroup
        EnhancementTab       matlab.ui.container.Tab
        HistEqTab            matlab.ui.container.Tab
        HistSpecTab          matlab.ui.container.Tab
        BrowseImageButton    matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            global img;
            [filename,pathname] = uigetfile({'*.bmp'}, 'Open Input Image');
            fullpathname = strcat(pathname, filename);
            img = imread(fullpathname);
            app.Image = img;
            imshow(img, 'parent', app.UIAxes);
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)

            [x,y,z] = size(app.Image);
            if z==1
                gsHist(app.Image)
            else
                rgbHist(app.Image);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnhancementTab = uitab(app.TabGroup);
            app.EnhancementTab.Title = 'Enhancement';
            app.HistEqTab = uitab(app.TabGroup);
            app.HistEqTab.Title = 'Hist Eq';
            app.HistSpecTab = uitab(app.TabGroup);
            app.HistSpecTab.Title = 'Hist Spec';
            app.ShowHistogramButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end